classdef App_v1_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure1                      matlab.ui.Figure
        axes                           matlab.ui.control.UIAxes
        tabs                           matlab.ui.container.TabGroup
        FormadeondaTab                 matlab.ui.container.Tab
        MostrarFormadeondaButton       matlab.ui.control.Button
        CargardatosButton              matlab.ui.control.Button
        ConvertiragPanel               matlab.ui.container.Panel
        ConvertirButton                matlab.ui.control.Button
        FondodeEscalagEditFieldLabel   matlab.ui.control.Label
        FondodeEscalagEditField        matlab.ui.control.NumericEditField
        BitsADCEditFieldLabel          matlab.ui.control.Label
        BitsADCEditField               matlab.ui.control.NumericEditField
        Eliminarcomponentea0HzPanel    matlab.ui.container.Panel
        AplicarButton                  matlab.ui.control.Button
        AmplitudarestarEditFieldLabel  matlab.ui.control.Label
        AmplitudarestarEditField       matlab.ui.control.NumericEditField
        EliminarGravedadLabel          matlab.ui.control.Label
        ControldelgrficoPanel          matlab.ui.container.Panel
        AmplitudSliderLabel            matlab.ui.control.Label
        AmplitudSlider                 matlab.ui.control.Slider
        TiempoSliderLabel              matlab.ui.control.Label
        TiempoSlider                   matlab.ui.control.Slider
        EspectroTab                    matlab.ui.container.Tab
        CalcularEspectroButton         matlab.ui.control.Button
        MagnitudButtonGroup            matlab.ui.container.ButtonGroup
        AceleracinButton               matlab.ui.control.RadioButton
        VelocidadButton                matlab.ui.control.RadioButton
        DesplazamientoButton           matlab.ui.control.RadioButton
        LinLogSwitchLabel              matlab.ui.control.Label
        LinLogSwitch                   matlab.ui.control.Switch
        PeakRMSSwitchLabel             matlab.ui.control.Label
        PeakRMSSwitch                  matlab.ui.control.Switch
        HzRPMSwitchLabel               matlab.ui.control.Label
        HzRPMSwitch                    matlab.ui.control.Switch
        UnidadEjeYSwitchLabel          matlab.ui.control.Label
        UnidadEjeYSwitch               matlab.ui.control.Switch
        SealaprocesarPanel             matlab.ui.container.Panel
        TraslapoSpinnerLabel           matlab.ui.control.Label
        TraslapoSpinner                matlab.ui.control.Spinner
        NdepromedioslinealesSpinnerLabel  matlab.ui.control.Label
        NdepromedioslinealesSpinner    matlab.ui.control.Spinner
        LargodelasealSpinnerLabel      matlab.ui.control.Label
        LargodelasealSpinner           matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        FiltroyventanaPanel            matlab.ui.container.Panel
        FiltropasaaltoHzSpinnerLabel   matlab.ui.control.Label
        FiltropasaaltoHzSpinner        matlab.ui.control.Spinner
        VentanaDropDownLabel           matlab.ui.control.Label
        VentanaDropDown                matlab.ui.control.DropDown
        FactordeCrestaEditFieldLabel   matlab.ui.control.Label
        FactordeCrestaEditField        matlab.ui.control.NumericEditField
        Aceleracinp2pgEditFieldLabel   matlab.ui.control.Label
        Aceleracinp2pgEditField        matlab.ui.control.NumericEditField
        AceleracinPeakgEditFieldLabel  matlab.ui.control.Label
        AceleracinPeakgEditField       matlab.ui.control.NumericEditField
        AceleracinRMSgEditFieldLabel   matlab.ui.control.Label
        AceleracinRMSgEditField        matlab.ui.control.NumericEditField
        DesplazamientoRMSmLabel        matlab.ui.control.Label
        DesplazamientoRMSmEditField    matlab.ui.control.NumericEditField
        VelocidadRMSmmsEditFieldLabel  matlab.ui.control.Label
        VelocidadRMSmmsEditField       matlab.ui.control.NumericEditField
        fHzEditFieldLabel              matlab.ui.control.Label
        fHzEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Fs;              % Frecuencia de muestreo [Hz]
        Ts;
        L;                % Tamaño de la señal
        tt;
        a; 
        Y; P2; P1; ff; P1v;
        P; f; v; d;
        hn; FCA = 1; % Ventana de Hanning, Factor de Factor de corrección de amplitud
        PR = 1; IsoSi = 1; HzRp = 1; % Peak o RMS, ISO o SI, Hz o RPM
    end
    
    methods (Access = private)
        
        function CalcularEspectroApeak(app)
            % Calcular el espectro de aceleración peak, usando promedios y traslapo

            % Verificar que el largo L, número de promedios y traslapo no requieran una señal más larga que la ingresada:
            if (app.L * (1-app.TraslapoSpinner.Value/100) * app.NdepromedioslinealesSpinner.Value) > length(app.a)
                helpdlg('La señal es incompatible con la selección de largo, número de promedios y porcentaje de traslapo.','Advertencia')
                app.LargodelasealSpinner.Value = 2;
                app.L = 2^(app.LargodelasealSpinner.Value);
                app.NdepromedioslinealesSpinner.Value = 1;
                app.TraslapoSpinner.Value = 0;
                if     app.VentanaDropDown.Value(1) == 'U'; app.hn  = ones(app.L,1); app.FCA = 1;
                elseif app.VentanaDropDown.Value(1) == 'H'; app.hn  = hann(app.L)  ; app.FCA = 2;
                end
            end
            
            app.P1v = zeros(app.NdepromedioslinealesSpinner.Value, app.L/2+1); % Preallocate vector que almacena los espectros que serán promediados
            TL = app.TraslapoSpinner.Value; % Traslapo (número entre 0 y 100)
            
            % FFT con traslapo:
            for i = 1:app.NdepromedioslinealesSpinner.Value
                
                app.Y = fft( app.a((i-1)*round(app.L*(1-TL/100)) + 1 : (i-1)*round(app.L*(1-TL/100)) + app.L) .* (app.hn') );
                app.P2 = abs(app.Y / app.L);             % "Espectro bilateral"
                app.P1 = [app.P2(1) 2*app.P2(2:app.L/2) app.P2(app.L/2+1)]; % "Espectro unilateral basado en el bilateral y longitud" [g] peak

                app.P1 = app.FCA * app.P1;               % Multiplicar el espectro por el factor de amplificación de amplitud
                app.ff = app.Fs * (0:(app.L/2)) / app.L; % Dominio de frecuencias
               
                app.P1v(i,:) = app.P1;                   % Agrega el espectro calculado al vector de espectros a promediar
            end
            
            
            app.P1 = sum(app.P1v,1) / app.NdepromedioslinealesSpinner.Value;    % Promediación lineal de espectros (contenidos en las filas de P1v) 
            
            app.f = app.ff( app.ff > app.FiltropasaaltoHzSpinner.Value ); % Dominio de frecuencias excluyendo aquellas inferiores o iguales a FPa
            app.P = app.P1( app.ff > app.FiltropasaaltoHzSpinner.Value ); % Espectro de aceleración peak [g] excluyendo los componentes a frecuencias inferiores o iguales a FPa
            
            app.v = 9.8 * 1000 /(2*pi) * app.P ./ app.f;                % Espectro de velocidad peak [mm/s]
            app.d = 1000 /(2*pi) * app.v ./ app.f;                      % Espectro de desplazamiento peak [um]
            
            
            app.fHzEditField.Value = (app.f(2) - app.f(1)) * app.HzRp;
            
            app.VelocidadRMSmmsEditField.Value    = sqrt(sum( (app.v(app.f<=1000)*sqrt(2)/2) .^2 )); % Vel  RMS digital [mm/s], desde la frecuencia del filtro pasa alto hasta 1000 [Hz]
            app.DesplazamientoRMSmEditField.Value = sqrt(sum( (app.d(app.f<=1000)*sqrt(2)/2) .^2 )); % Desp RMS digital [um],   desde la frecuencia del filtro pasa alto hasta 1000 [Hz]
            
            app.AceleracinRMSgEditField.Value  = sqrt(sum( (app.P(app.f<=1000)*sqrt(2)/2) .^2 ));    % Acel RMS digital [g],    desde la frecuencia del filtro pasa alto hasta 1000 [Hz]
            app.AceleracinPeakgEditField.Value = max(abs(app.a)); % Valor máximo absoluto de aceleración [g]
            app.Aceleracinp2pgEditField.Value  = max(app.a) + abs(min(app.a)); % Valor máximo positivo menos valor máximo negativo de aceleración [g]
            app.FactordeCrestaEditField.Value  = app.AceleracinPeakgEditField.Value / app.AceleracinRMSgEditField.Value; % Factor de Cresta de aceleración
                        
            
            app.axes.cla;
            plot(app.axes, app.f, app.P,'Color','k','LineWidth',1,'LineJoin','miter');
            
            title (app.axes, ['Espectro de ' app.MagnitudButtonGroup.SelectedObject.Text ' ' app.PeakRMSSwitch.Value])
            xlabel(app.axes, ['Frecuencia [' app.HzRPMSwitch.Value ']'])
            ylabel(app.axes, [app.MagnitudButtonGroup.SelectedObject.Text ' ' app.PeakRMSSwitch.Value ' [' app.UnidadEjeYSwitch.Value ']'])
            axis  (app.axes, [app.ff(1) app.ff(end) 0 max(app.P1)*1.01])
        end
        
        
        function Graficar(app)
            
            app.axes.cla; axis(app.axes,'on')

            if app.AceleracinButton.Value
                plot(app.axes, app.f * app.HzRp, app.P * app.PR * app.IsoSi,'Color','k','LineWidth',1,'LineJoin','miter');
                axis(app.axes, [app.ff(1) app.ff(end)*app.HzRp 0 max(app.P*app.IsoSi)*1.01])
                
            elseif app.VelocidadButton.Value
                plot(app.axes, app.f * app.HzRp, app.v * app.PR * app.IsoSi,'Color','k','LineWidth',1,'LineJoin','miter');
                axis(app.axes, [app.ff(1) app.ff(end)*app.HzRp 0 max(app.v*app.IsoSi)*1.01])
                
            elseif app.DesplazamientoButton.Value
                plot(app.axes, app.f * app.HzRp, app.d * app.PR * app.IsoSi,'Color','k','LineWidth',1,'LineJoin','miter');
                axis(app.axes, [app.ff(1) app.ff(end)*app.HzRp 0 max(app.d*app.IsoSi)*1.01])
            end
            
            title (app.axes, ['Espectro de ' app.MagnitudButtonGroup.SelectedObject.Text ' ' app.PeakRMSSwitch.Value])
            xlabel(app.axes, ['Frecuencia [' app.HzRPMSwitch.Value ']'])
            ylabel(app.axes, [app.MagnitudButtonGroup.SelectedObject.Text ' ' app.PeakRMSSwitch.Value ' [' app.UnidadEjeYSwitch.Value ']'])
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.L = 2^(app.LargodelasealSpinner.Value);
            
            if app.VentanaDropDown.Value(1) == 'U'     
                app.hn  = ones(app.L,1); 
                app.FCA = 1;
            elseif app.VentanaDropDown.Value(1) == 'H'
                app.hn  = hann(app.L);   
                app.FCA = 2;
            end
            
            title (app.axes, ' ')
            xlabel(app.axes, ' ')
            ylabel(app.axes, ' ')
            axis  (app.axes,'off')
            
            app.EliminarGravedadLabel.Text = sprintf('Ingresar aceleración de gravedad\nmedida por el sensor, a restar de\nlos valores de la señal.');
        end

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
            [archivo,ruta] = uigetfile('*.txt','Archivo de datos');
            figure(app.UIFigure1);
            IDtxt = fopen(strcat(ruta,archivo),'r');
            datos = textscan(IDtxt,'%f');
            fclose(IDtxt);
            app.axes.cla;
            
            app.a  = datos{1,1};
            tm     = app.a(end)/1000;       % tiempo total de medición [s]
            app.a  = app.a(1:end-1);        % aceleraciones [g]
            app.a  = app.a';
            app.Fs = length(app.a) / tm;    % Frecuencia de muestreo [Hz]
            app.tt = linspace(0, tm, length( app.a(1:end) ) ); % Vector de tiempo [s]
        end

        % Button pushed function: MostrarFormadeondaButton
        function MostrarFormadeondaButtonPushed(app, event)
            app.axes.cla; axis(app.axes,'on')
            plot(app.axes, app.tt, app.a,'LineJoin','miter','Color','k','LineWidth',1);
            title(app.axes,'Forma de onda')
            xlabel(app.axes,'Tiempo [s]')
            ylabel(app.axes,'Aceleración [g]')
            axis(app.axes, [app.tt(1) app.tt(end)*app.TiempoSlider.Value/100 -max(app.a)*1.1*app.AmplitudSlider.Value/100 max(app.a)*1.1*app.AmplitudSlider.Value/100])
        end

        % Button pushed function: CalcularEspectroButton
        function CalcularEspectroButtonPushed(app, event)
            CalcularEspectroApeak(app);
            Graficar(app);
        end

        % Selection changed function: MagnitudButtonGroup
        function MagnitudButtonGroupSelectionChanged(app, event)

            if app.AceleracinButton.Value
                app.UnidadEjeYSwitch.Items = {'g','m/s²'};
                
            elseif app.VelocidadButton.Value
                app.UnidadEjeYSwitch.Items = {'mm/s','mm/s'};
                
            elseif app.DesplazamientoButton.Value
                app.UnidadEjeYSwitch.Items = {'μm','mm'};
            end
            
            Graficar(app);
            
        end

        % Value changed function: NdepromedioslinealesSpinner
        function NdepromedioslinealesSpinnerValueChanged(app, event)
            CalcularEspectroApeak(app); Graficar(app);
        end

        % Value changed function: TraslapoSpinner
        function TraslapoSpinnerValueChanged(app, event)
            CalcularEspectroApeak(app); Graficar(app);
        end

        % Value changed function: FiltropasaaltoHzSpinner
        function FiltropasaaltoHzSpinnerValueChanged(app, event)
            CalcularEspectroApeak(app); Graficar(app);
        end

        % Value changed function: VentanaDropDown
        function VentanaDropDownValueChanged(app, event)
            if app.VentanaDropDown.Value(1) == 'U'     
                app.hn  = ones(app.L,1); 
                app.FCA = 1;
            elseif app.VentanaDropDown.Value(1) == 'H'
                app.hn  = hann(app.L);   
                app.FCA = 2;
            end
            CalcularEspectroApeak(app);
            Graficar(app);
        end

        % Value changed function: PeakRMSSwitch
        function PeakRMSSwitchValueChanged(app, event)
            if app.PeakRMSSwitch.Value(1) == 'P'     
                app.PR = 1;
            elseif app.PeakRMSSwitch.Value(1) == 'R'
                app.PR = sqrt(2)/2;
            end
            Graficar(app);
        end

        % Value changed function: LinLogSwitch
        function LinLogSwitchValueChanged(app, event)
            app.axes.YScale = app.LinLogSwitch.Value;
        end

        % Value changed function: UnidadEjeYSwitch
        function UnidadEjeYSwitchValueChanged(app, event)
            if app.AceleracinButton.Value
                if      app.UnidadEjeYSwitch.Value(1) == 'g'; app.IsoSi = 1;
                elseif  app.UnidadEjeYSwitch.Value(1) == 'm'; app.IsoSi = 9.80665; end
                
            elseif app.VelocidadButton.Value
                app.IsoSi = 1;
                
            elseif app.DesplazamientoButton.Value
                if app.UnidadEjeYSwitch.Value(1) == 'm'
                    app.IsoSi = 0.001;
                else
                    app.IsoSi = 1;
                end
            end
            Graficar(app);
        end

        % Value changed function: HzRPMSwitch
        function HzRPMSwitchValueChanged(app, event)
            if app.HzRPMSwitch.Value(1) == 'H'
                app.HzRp = 1;
                app.fHzEditFieldLabel.Text = 'Δf [Hz]';
            else
                app.HzRp = 60;
                app.fHzEditFieldLabel.Text = 'Δf [RPM]';
            end
            app.fHzEditField.Value = (app.f(2) - app.f(1)) * app.HzRp;
            Graficar(app);
        end

        % Value changed function: AmplitudSlider
        function AmplitudSliderValueChanged(app, event)
            MostrarFormadeondaButtonPushed(app);
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)
            MostrarFormadeondaButtonPushed(app);
        end

        % Button pushed function: ConvertirButton
        function ConvertirButtonPushed(app, event)
            app.a = ((app.a - 0) * (app.FondodeEscalagEditField.Value - -app.FondodeEscalagEditField.Value)) / ((2^app.BitsADCEditField.Value)-1 - 0) + (-app.FondodeEscalagEditField.Value);
            MostrarFormadeondaButtonPushed(app);
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            app.a = app.a - app.AmplitudarestarEditField.Value;
            MostrarFormadeondaButtonPushed(app);
        end

        % Value changed function: LargodelasealSpinner
        function LargodelasealSpinnerValueChanged(app, event)
            app.L = 2^(app.LargodelasealSpinner.Value);
            
            if app.VentanaDropDown.Value(1) == 'U'     
                app.hn  = ones(app.L,1); 
                app.FCA = 1;
            elseif app.VentanaDropDown.Value(1) == 'H'
                app.hn  = hann(app.L);   
                app.FCA = 2;
            end
            
            CalcularEspectroApeak(app);
            Graficar(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure1 = uifigure('Visible', 'off');
            app.UIFigure1.Name = 'UI Figure';
            app.axes = uiaxes(app.UIFigure1);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            app.tabs = uitabgroup(app.UIFigure1);
            app.FormadeondaTab = uitab(app.tabs);
            app.FormadeondaTab.Title = 'Forma de onda';
            app.MostrarFormadeondaButton = uibutton(app.FormadeondaTab, 'push');
            app.MostrarFormadeondaButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarFormadeondaButtonPushed, true);
            app.MostrarFormadeondaButton.Text = 'Mostrar Forma de onda';
            app.CargardatosButton = uibutton(app.FormadeondaTab, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.ConvertiragPanel = uipanel(app.FormadeondaTab);
            app.ConvertiragPanel.Title = 'Convertir a [g]';
            app.ConvertirButton = uibutton(app.ConvertiragPanel, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton.Text = 'Convertir';
            app.FondodeEscalagEditFieldLabel = uilabel(app.ConvertiragPanel);
            app.FondodeEscalagEditFieldLabel.Text = 'Fondo de Escala [g]';
            app.FondodeEscalagEditField = uieditfield(app.ConvertiragPanel, 'numeric');
            app.FondodeEscalagEditField.Limits = [-500 500];
            app.FondodeEscalagEditField.ValueDisplayFormat = '%3.0f';
            app.FondodeEscalagEditField.Value = 10;
            app.BitsADCEditFieldLabel = uilabel(app.ConvertiragPanel);
            app.BitsADCEditFieldLabel.Text = {'Bits ADC'; ''};
            app.BitsADCEditField = uieditfield(app.ConvertiragPanel, 'numeric');
            app.BitsADCEditField.Limits = [2 64];
            app.BitsADCEditField.Value = 10;
            app.Eliminarcomponentea0HzPanel = uipanel(app.FormadeondaTab);
            app.Eliminarcomponentea0HzPanel.Title = 'Eliminar componente a 0 Hz';
            app.AplicarButton = uibutton(app.Eliminarcomponentea0HzPanel, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.AmplitudarestarEditFieldLabel = uilabel(app.Eliminarcomponentea0HzPanel);
            app.AmplitudarestarEditFieldLabel.Text = 'Amplitud a restar ';
            app.AmplitudarestarEditField = uieditfield(app.Eliminarcomponentea0HzPanel, 'numeric');
            app.AmplitudarestarEditField.Limits = [0 2];
            app.AmplitudarestarEditField.ValueDisplayFormat = '%4.2f';
            app.AmplitudarestarEditField.Value = 1.16;
            app.EliminarGravedadLabel = uilabel(app.Eliminarcomponentea0HzPanel);
            app.EliminarGravedadLabel.Text = 'Ingresar aceleración de gravedad';
            app.ControldelgrficoPanel = uipanel(app.FormadeondaTab);
            app.ControldelgrficoPanel.Title = 'Control del gráfico';
            app.AmplitudSliderLabel = uilabel(app.ControldelgrficoPanel);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.ControldelgrficoPanel);
            app.AmplitudSlider.Limits = [0 200];
            app.AmplitudSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200];
            app.AmplitudSlider.MajorTickLabels = {'0%', '20%', '40%', '60%', '80%', '100%', '120%', '140%', '160%', '180%', '200%'};
            app.AmplitudSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.AmplitudSlider.MinorTicks = [10 30 50 70 90 110 130 150 170 190];
            app.AmplitudSlider.Value = 100;
            app.TiempoSliderLabel = uilabel(app.ControldelgrficoPanel);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.ControldelgrficoPanel);
            app.TiempoSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TiempoSlider.MajorTickLabels = {'0%', '10%', '20%', '30%', '40%', '50%', '60%', '70%', '80%', '90%', '100%'};
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.TiempoSlider.MinorTicks = [5 15 25 35 45 55 65 75 85 95];
            app.TiempoSlider.Value = 100;
            app.EspectroTab = uitab(app.tabs);
            app.EspectroTab.Title = 'Espectro';
            app.CalcularEspectroButton = uibutton(app.EspectroTab, 'push');
            app.CalcularEspectroButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularEspectroButtonPushed, true);
            app.CalcularEspectroButton.Text = 'Calcular Espectro';
            app.MagnitudButtonGroup = uibuttongroup(app.EspectroTab);
            app.MagnitudButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnitudButtonGroupSelectionChanged, true);
            app.MagnitudButtonGroup.Title = 'Magnitud';
            app.AceleracinButton = uiradiobutton(app.MagnitudButtonGroup);
            app.AceleracinButton.Text = 'Aceleración';
            app.AceleracinButton.Value = true;
            app.VelocidadButton = uiradiobutton(app.MagnitudButtonGroup);
            app.VelocidadButton.Text = 'Velocidad';
            app.DesplazamientoButton = uiradiobutton(app.MagnitudButtonGroup);
            app.DesplazamientoButton.Text = 'Desplazamiento';
            app.LinLogSwitchLabel = uilabel(app.EspectroTab);
            app.LinLogSwitchLabel.Text = 'Lin/Log';
            app.LinLogSwitch = uiswitch(app.EspectroTab, 'slider');
            app.LinLogSwitch.Items = {'Linear', 'Log'};
            app.LinLogSwitch.ValueChangedFcn = createCallbackFcn(app, @LinLogSwitchValueChanged, true);
            app.LinLogSwitch.Value = 'Linear';
            app.PeakRMSSwitchLabel = uilabel(app.EspectroTab);
            app.PeakRMSSwitchLabel.Text = 'Peak/RMS';
            app.PeakRMSSwitch = uiswitch(app.EspectroTab, 'slider');
            app.PeakRMSSwitch.Items = {'Peak', 'RMS'};
            app.PeakRMSSwitch.ValueChangedFcn = createCallbackFcn(app, @PeakRMSSwitchValueChanged, true);
            app.PeakRMSSwitch.Value = 'Peak';
            app.HzRPMSwitchLabel = uilabel(app.EspectroTab);
            app.HzRPMSwitchLabel.Text = 'Hz/RPM';
            app.HzRPMSwitch = uiswitch(app.EspectroTab, 'slider');
            app.HzRPMSwitch.Items = {'Hz', 'RPM'};
            app.HzRPMSwitch.ValueChangedFcn = createCallbackFcn(app, @HzRPMSwitchValueChanged, true);
            app.HzRPMSwitch.Value = 'Hz';
            app.UnidadEjeYSwitchLabel = uilabel(app.EspectroTab);
            app.UnidadEjeYSwitchLabel.Text = 'Unidad Eje Y';
            app.UnidadEjeYSwitch = uiswitch(app.EspectroTab, 'slider');
            app.UnidadEjeYSwitch.Items = {'g', 'm/s²'};
            app.UnidadEjeYSwitch.ValueChangedFcn = createCallbackFcn(app, @UnidadEjeYSwitchValueChanged, true);
            app.UnidadEjeYSwitch.Value = 'g';
            app.SealaprocesarPanel = uipanel(app.EspectroTab);
            app.SealaprocesarPanel.Title = 'Señal a procesar';
            app.TraslapoSpinnerLabel = uilabel(app.SealaprocesarPanel);
            app.TraslapoSpinnerLabel.Text = 'Traslapo [%]';
            app.TraslapoSpinner = uispinner(app.SealaprocesarPanel);
            app.TraslapoSpinner.Step = 10;
            app.TraslapoSpinner.Limits = [0 99];
            app.TraslapoSpinner.ValueDisplayFormat = '%2.0f';
            app.TraslapoSpinner.ValueChangedFcn = createCallbackFcn(app, @TraslapoSpinnerValueChanged, true);
            app.NdepromedioslinealesSpinnerLabel = uilabel(app.SealaprocesarPanel);
            app.NdepromedioslinealesSpinnerLabel.Text = 'N° de promedios lineales';
            app.NdepromedioslinealesSpinner = uispinner(app.SealaprocesarPanel);
            app.NdepromedioslinealesSpinner.Limits = [1 200];
            app.NdepromedioslinealesSpinner.ValueDisplayFormat = '%2.0f';
            app.NdepromedioslinealesSpinner.ValueChangedFcn = createCallbackFcn(app, @NdepromedioslinealesSpinnerValueChanged, true);
            app.NdepromedioslinealesSpinner.Value = 1;
            app.LargodelasealSpinnerLabel = uilabel(app.SealaprocesarPanel);
            app.LargodelasealSpinnerLabel.Text = 'Largo de la señal';
            app.LargodelasealSpinner = uispinner(app.SealaprocesarPanel);
            app.LargodelasealSpinner.Limits = [1 Inf];
            app.LargodelasealSpinner.ValueChangedFcn = createCallbackFcn(app, @LargodelasealSpinnerValueChanged, true);
            app.LargodelasealSpinner.Value = 9;
            app.Label = uilabel(app.SealaprocesarPanel);
            app.Label.Text = '2 ^';
            app.FiltroyventanaPanel = uipanel(app.EspectroTab);
            app.FiltroyventanaPanel.Title = 'Filtro y ventana';
            app.FiltropasaaltoHzSpinnerLabel = uilabel(app.FiltroyventanaPanel);
            app.FiltropasaaltoHzSpinnerLabel.Text = 'Filtro pasa alto [Hz]';
            app.FiltropasaaltoHzSpinner = uispinner(app.FiltroyventanaPanel);
            app.FiltropasaaltoHzSpinner.Step = 10;
            app.FiltropasaaltoHzSpinner.Limits = [1 1000];
            app.FiltropasaaltoHzSpinner.ValueDisplayFormat = '%2.0f';
            app.FiltropasaaltoHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FiltropasaaltoHzSpinnerValueChanged, true);
            app.FiltropasaaltoHzSpinner.Value = 200;
            app.VentanaDropDownLabel = uilabel(app.FiltroyventanaPanel);
            app.VentanaDropDownLabel.Text = 'Ventana';
            app.VentanaDropDown = uidropdown(app.FiltroyventanaPanel);
            app.VentanaDropDown.Items = {'Uniforme', 'Hanning'};
            app.VentanaDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanaDropDownValueChanged, true);
            app.VentanaDropDown.Value = 'Uniforme';
            app.FactordeCrestaEditFieldLabel = uilabel(app.UIFigure1);
            app.FactordeCrestaEditFieldLabel.Text = 'Factor de Cresta';
            app.FactordeCrestaEditField = uieditfield(app.UIFigure1, 'numeric');
            app.FactordeCrestaEditField.ValueDisplayFormat = '%4.2f';
            app.FactordeCrestaEditField.Editable = 'off';
            app.Aceleracinp2pgEditFieldLabel = uilabel(app.UIFigure1);
            app.Aceleracinp2pgEditFieldLabel.Text = 'Aceleración p2p [g]';
            app.Aceleracinp2pgEditField = uieditfield(app.UIFigure1, 'numeric');
            app.Aceleracinp2pgEditField.ValueDisplayFormat = '%4.1f';
            app.Aceleracinp2pgEditField.Editable = 'off';
            app.AceleracinPeakgEditFieldLabel = uilabel(app.UIFigure1);
            app.AceleracinPeakgEditFieldLabel.Text = 'Aceleración Peak [g]';
            app.AceleracinPeakgEditField = uieditfield(app.UIFigure1, 'numeric');
            app.AceleracinPeakgEditField.ValueDisplayFormat = '%4.1f';
            app.AceleracinPeakgEditField.Editable = 'off';
            app.AceleracinRMSgEditFieldLabel = uilabel(app.UIFigure1);
            app.AceleracinRMSgEditFieldLabel.Text = 'Aceleración RMS [g]';
            app.AceleracinRMSgEditField = uieditfield(app.UIFigure1, 'numeric');
            app.AceleracinRMSgEditField.ValueDisplayFormat = '%4.1f';
            app.AceleracinRMSgEditField.Editable = 'off';
            app.DesplazamientoRMSmLabel = uilabel(app.UIFigure1);
            app.DesplazamientoRMSmLabel.Text = {'Desplazamiento RMS '; '[μm]'};
            app.DesplazamientoRMSmEditField = uieditfield(app.UIFigure1, 'numeric');
            app.DesplazamientoRMSmEditField.ValueDisplayFormat = '%3.1f';
            app.DesplazamientoRMSmEditField.Editable = 'off';
            app.VelocidadRMSmmsEditFieldLabel = uilabel(app.UIFigure1);
            app.VelocidadRMSmmsEditFieldLabel.Text = 'Velocidad RMS [mm/s]';
            app.VelocidadRMSmmsEditField = uieditfield(app.UIFigure1, 'numeric');
            app.VelocidadRMSmmsEditField.ValueDisplayFormat = '%4.1f';
            app.VelocidadRMSmmsEditField.Editable = 'off';
            app.fHzEditFieldLabel = uilabel(app.UIFigure1);
            app.fHzEditFieldLabel.Text = 'Δf [Hz]';
            app.fHzEditField = uieditfield(app.UIFigure1, 'numeric');
            app.fHzEditField.ValueDisplayFormat = '%4.1f';
            app.fHzEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v1_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure1)
        end
    end
end